classdef NoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        NoiseAxes            matlab.ui.control.UIAxes
        StartEditFieldLabel  matlab.ui.control.Label
        StartEditField       matlab.ui.control.EditField
        EndEditFieldLabel    matlab.ui.control.Label
        EndEditField         matlab.ui.control.EditField
        TimeLabel            matlab.ui.control.Label
        CalculateButton      matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        ImportButton         matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        ButtonGroup          matlab.ui.container.ButtonGroup
        ImportfileButton     matlab.ui.control.RadioButton
        ImportfolderButton   matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Patient;
    end
    
    methods (Access = private)
        
        function results = findWorkHours(app)
        % Needs fixing
           afterHoursTable = readtable('Data\Fyraften.csv');
           dataTable = afterHoursTable(afterHoursTable.PatientID == str2num(app.Patient.ID),:);
           
           time = strsplit(num2str(dataTable.Fyraften),'.');
           timeToUse = zeros(1,3);
           timeToUse(1,1) = time(1,1);
           timeToUse(1,2) = time(1,2);
           timeToUse(1,3) = '00';
           timeToUse;
           
           
           endOfWork = datetime({dataTable.Fyraften},'InputFormat','HH.mm',"Format","HH:mm:ss" );
           app.Patient.Work.Stop = endOfWork;
           
           app.Patient.Work.Start = app.Patient.Measurement(1,:).Time;
           app.Patient
        end
        
        function results = findCompanyInformation(app)
            keyTable = readtable('Data/Noeglefil.csv');
            dataTable = keyTable(keyTable.PatientID == str2num(app.Patient.ID), :);
            app.Patient.company.ID = dataTable.VirksomhedsID;
            app.Patient.company.Name = dataTable.VirksomhedsNavn;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [FileName, Pathname] = uigetfile();
            if FileName ~=0
                Fullpath = fullfile(Pathname, FileName);
                app.Patient.Measurement = importSingleFileFunction(Fullpath);
                IdCellArray = strsplit(FileName, '.'); %%Splitting Filename into two parts; Filename and file extension
                app.Patient.ID = IdCellArray{1,:};
                findCompanyInformation(app);
                findWorkHours(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.NoiseAxes = uiaxes(app.Panel);
            title(app.NoiseAxes, 'Title')
            xlabel(app.NoiseAxes, 'Time(s)')
            ylabel(app.NoiseAxes, 'Sound Pressure Level[dB]')
            app.StartEditFieldLabel = uilabel(app.Panel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Panel, 'text');
            app.EndEditFieldLabel = uilabel(app.Panel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.Panel, 'text');
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ImportButton = uibutton(app.Panel_2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.Panel_2, 'push');
            app.ExportButton.Text = 'Export';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ImportfileButton = uiradiobutton(app.ButtonGroup);
            app.ImportfileButton.Text = 'Import file';
            app.ImportfileButton.Value = true;
            app.ImportfolderButton = uiradiobutton(app.ButtonGroup);
            app.ImportfolderButton.Text = 'Import folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NoiseApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end